classdef TravelTimeCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EntersourcexcoordinatesEditFieldLabel  matlab.ui.control.Label
        EntersourcexcoordinatesEditField  matlab.ui.control.NumericEditField
        LoadmaterialmapDropDownLabel    matlab.ui.control.Label
        LoadmaterialmapDropDown         matlab.ui.control.DropDown
        PlotTravelTimeFieldButton       matlab.ui.control.Button
        EntersourceycoordinatesEditFieldLabel  matlab.ui.control.Label
        EntersourceycoordinatesEditField  matlab.ui.control.NumericEditField
        PlotMaterialMapButton           matlab.ui.control.Button
        TravelTimesCalculatorLabel      matlab.ui.control.Label
        TravelTimesatReceiversButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        SaveTravelTimesasdatFileButton  matlab.ui.control.Button
        EnterreceiverxcoordinatesEditField_2Label  matlab.ui.control.Label
        EnterreceiverxcoordinatesEditField_2  matlab.ui.control.EditField
        EnterreceiverycoordinatesEditField_2Label  matlab.ui.control.Label
        EnterreceiverycoordinatesEditField_2  matlab.ui.control.EditField
        ClearallButton                  matlab.ui.control.Button
        ClearplotsButton                matlab.ui.control.Button
        ScalingEditFieldLabel           matlab.ui.control.Label
        ScalingEditField                matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        matmap % material properties
        sw1
        rxConverted
        ryConverted
        ttf
        dnx
        srtime
        valuex
        valuey
    end
    

    methods (Access = private)

        % Button pushed function: PlotTravelTimeFieldButton
        function PlotTravelTimeFieldButtonPushed(app, event)
            app.UIAxes3.cla
            m=app.matmap;
            app.valuex = app.EntersourcexcoordinatesEditField.Value;
            if app.valuex<=0 || app.valuex>size(m,2)
            f = uifigure;
            uialert(f,'Source (x) lies outside bounds','Input error');
            end
            
            app.valuey = app.EntersourceycoordinatesEditField.Value;
            if app.valuey<=0 || app.valuey>size(m,1)
            f = uifigure;
            uialert(f,'Source (y) lies outside bounds','Input error');
            end
                
            
            app.dnx = app.ScalingEditField.Value;
            if app.dnx==0
            app.dnx=1; % default no scaling
            f = uifigure;
            uialert(f,'No scaling provided - set as 1 as default','Warning');
            end     
            
            app.ttf=modrays(app.valuex-1,app.valuey-1,m);
            app.ttf=app.ttf*app.dnx;
            
            app.UIAxes3.XLim = [1 size(m,2)];
            app.UIAxes3.YLim = [1 size(m,1)];
            
            imagesc(app.UIAxes3,app.ttf)
            app.UIAxes3.YDir = 'normal';
            app.UIAxes3.Colormap = colormap(app.UIAxes3,'lines');
            hold(app.UIAxes3);
            plot(app.UIAxes3,app.valuex,app.valuey,'rx','LineWidth',2)
  
        end

        % Value changed function: LoadmaterialmapDropDown
        function LoadmaterialmapDropDownValueChanged(app, event)
            value = app.LoadmaterialmapDropDown.Value;
                if strcmp(value,'Homogeneous')
                  map=5900*ones(100,100);
                  err_sw=0;
                elseif strcmp(value,'Random')
                  map=randi([5500,6500],100);
                  err_sw=0;
                elseif strcmp(value,'Blocks')
                  map1=horzcat(5900*ones(50,50),4300*ones(50,50));
                  map2=horzcat(5000*ones(50,50),6800*ones(50,50));
                  map=vertcat(map1,map2);
                  err_sw=0;
                elseif strcmp(value,'Select one')
                  err_sw=1;
                  map=0;
                elseif strcmp(value,'Other')
                  Browsefilename = uigetfile('*.mat');
                  loadmap=load(Browsefilename);
                  map=cell2mat(struct2cell(loadmap));
                  err_sw=0;
                end
                
                app.matmap=map;
                app.sw1=err_sw;
        end

        % Button pushed function: PlotMaterialMapButton
        function PlotMaterialMapButtonPushed(app, event)
            app.UIAxes2.cla
             if app.sw1==1
                f = uifigure;
                uialert(f,'Invalid map selection made','Input error');
             end
            m=app.matmap;
            app.UIAxes2.XLim = [1 size(m,2)];
            app.UIAxes2.YLim = [1 size(m,1)];
            imagesc(app.UIAxes2,app.matmap)
            app.UIAxes2.YDir = 'normal';
        end

        % Button pushed function: TravelTimesatReceiversButton
        function TravelTimesatReceiversButtonPushed(app, event)
            app.UIAxes.cla
            app.UIAxes3.cla
            
            imagesc(app.UIAxes3,app.ttf)
            app.UIAxes3.YDir = 'normal';
            app.UIAxes3.Colormap = colormap(app.UIAxes3,'lines');
            hold(app.UIAxes3);
            plot(app.UIAxes3,app.valuex,app.valuey,'rx','LineWidth',2)
            
            
            rx = app.EnterreceiverxcoordinatesEditField_2.Value;
            TFx = isstrprop(rx,'alpha');
            if sum(TFx)~=0
            f = uifigure;
            uialert(f,'Receiver (x) input not numeric','Input error');
            end
            
            ry = app.EnterreceiverycoordinatesEditField_2.Value;
            TFy = isstrprop(ry,'alpha');
            if sum(TFy)~=0
            f = uifigure;
            uialert(f,'Receiver (y) input not numeric','Input error');
            end
            
            m=app.matmap;         
            app.rxConverted=str2double(strsplit(rx,','));
            app.ryConverted=str2double(strsplit(ry,','));
            
            if min(app.rxConverted)<=0 || max(app.rxConverted)>size(m,2) || min(app.ryConverted)<=0 || max(app.ryConverted)>size(m,1)
            f = uifigure;
            uialert(f,'Receivers out of bounds','Input error');
            elseif length(app.rxConverted)~=length(app.ryConverted)
            f = uifigure;
            uialert(f,'X and y receiver coordinate vectors must be of equal length','Input error');
            else
            plot(app.UIAxes3,app.rxConverted,app.ryConverted,'kx','LineWidth',2)
            end        
           
            app.srtime=[]
            for i=1:length(app.rxConverted)
            app.srtime(i)=app.ttf(app.ryConverted(i),app.rxConverted(i));
            end

            plot(app.UIAxes,app.srtime,'-*')
            if min(app.srtime)~=max(app.srtime)
            app.UIAxes.YLim = [0.99*min(app.srtime) 1.01*max(app.srtime)];
            end
        end

        % Button pushed function: SaveTravelTimesasdatFileButton
        function SaveTravelTimesasdatFileButtonPushed(app, event)
            rx1=app.rxConverted;
            ry1=app.ryConverted;
            srt1=app.srtime;
            tdata=[rx1;ry1;srt1];
                fid = fopen('traveltimes.dat','w');
                fprintf(fid, '%4.6f %4.6f %4.12f\n',tdata);
                fclose(fid);
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
             app.UIAxes.cla
             app.UIAxes2.cla
             app.UIAxes3.cla
             app.EntersourcexcoordinatesEditField.Value=0;
             app.EntersourceycoordinatesEditField.Value=0;
             app.ScalingEditField.Value=0;
             app.LoadmaterialmapDropDown.Value='Select one';
             app.EnterreceiverxcoordinatesEditField_2.Value='';
             app.EnterreceiverycoordinatesEditField_2.Value='';
        end

        % Button pushed function: ClearplotsButton
        function ClearplotsButtonPushed(app, event)
             app.UIAxes.cla
             app.UIAxes2.cla
             app.UIAxes3.cla
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EntersourcexcoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EntersourcexcoordinatesEditFieldLabel.Text = 'Enter source x coordinates';
            app.EntersourcexcoordinatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadmaterialmapDropDownLabel = uilabel(app.UIFigure);
            app.LoadmaterialmapDropDownLabel.Text = 'Load material map';
            app.LoadmaterialmapDropDown = uidropdown(app.UIFigure);
            app.LoadmaterialmapDropDown.Items = {'Select one', 'Homogeneous', 'Random', 'Blocks', 'Other'};
            app.LoadmaterialmapDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadmaterialmapDropDownValueChanged, true);
            app.LoadmaterialmapDropDown.Value = 'Select one';
            app.PlotTravelTimeFieldButton = uibutton(app.UIFigure, 'push');
            app.PlotTravelTimeFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTravelTimeFieldButtonPushed, true);
            app.PlotTravelTimeFieldButton.Text = 'Plot Travel Time Field';
            app.EntersourceycoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.EntersourceycoordinatesEditFieldLabel.Text = 'Enter source y coordinates';
            app.EntersourceycoordinatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotMaterialMapButton = uibutton(app.UIFigure, 'push');
            app.PlotMaterialMapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMaterialMapButtonPushed, true);
            app.PlotMaterialMapButton.Text = 'Plot Material Map';
            app.TravelTimesCalculatorLabel = uilabel(app.UIFigure);
            app.TravelTimesCalculatorLabel.Text = 'Travel Times Calculator';
            app.TravelTimesatReceiversButton = uibutton(app.UIFigure, 'push');
            app.TravelTimesatReceiversButton.ButtonPushedFcn = createCallbackFcn(app, @TravelTimesatReceiversButtonPushed, true);
            app.TravelTimesatReceiversButton.Text = 'Travel Times at Receivers';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Travel time to Receivers')
            xlabel(app.UIAxes, 'Receiver Index')
            ylabel(app.UIAxes, 'Travel Time')
            app.SaveTravelTimesasdatFileButton = uibutton(app.UIFigure, 'push');
            app.SaveTravelTimesasdatFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTravelTimesasdatFileButtonPushed, true);
            app.SaveTravelTimesasdatFileButton.Text = 'Save Travel Times as .dat File';
            app.EnterreceiverxcoordinatesEditField_2Label = uilabel(app.UIFigure);
            app.EnterreceiverxcoordinatesEditField_2Label.Text = 'Enter receiver x coordinates';
            app.EnterreceiverxcoordinatesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EnterreceiverycoordinatesEditField_2Label = uilabel(app.UIFigure);
            app.EnterreceiverycoordinatesEditField_2Label.Text = 'Enter receiver y coordinates';
            app.EnterreceiverycoordinatesEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ClearallButton = uibutton(app.UIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.ClearplotsButton = uibutton(app.UIFigure, 'push');
            app.ClearplotsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotsButtonPushed, true);
            app.ClearplotsButton.Text = 'Clear plots';
            app.ScalingEditFieldLabel = uilabel(app.UIFigure);
            app.ScalingEditFieldLabel.Text = 'Scaling';
            app.ScalingEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = TravelTimeCalculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end